classdef file_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FileDetailsUIFigure           matlab.ui.Figure
        DatafiledetailsPanel          matlab.ui.container.Panel
        CovariatedatafileEditFieldLabel  matlab.ui.control.Label
        CovariatedatafileEditField    matlab.ui.control.EditField
        TargetdatafileEditFieldLabel  matlab.ui.control.Label
        TargetdatafileEditField       matlab.ui.control.EditField
        PriortemplatefileEditFieldLabel  matlab.ui.control.Label
        PriortemplatefileEditField    matlab.ui.control.EditField
        KerneltemplatefileEditFieldLabel  matlab.ui.control.Label
        KerneltemplatefileEditField   matlab.ui.control.EditField
        CovariateSetButton            matlab.ui.control.Button
        TargetSetButton               matlab.ui.control.Button
        PriorSetButton                matlab.ui.control.Button
        KernelSetButton               matlab.ui.control.Button
        ProcessfilesButton            matlab.ui.control.Button
        OrderDropDownLabel            matlab.ui.control.Label
        TargetOrderDropDown           matlab.ui.control.DropDown
        OrderDropDown_2Label          matlab.ui.control.Label
        CovariateOrderDropDown        matlab.ui.control.DropDown
        ResultsdirectorylocationEditFieldLabel  matlab.ui.control.Label
        ResultsdirectorylocationEditField  matlab.ui.control.EditField
        ResultSetButton               matlab.ui.control.Button
        BackButton                    matlab.ui.control.Button
    end


    properties (Access = public)
        
        % Specify public properties
        covariateDataFile=''; 
        targetDataFile='';
        priorTemplateFile='';
        kernelTemplateFile='';
        generateLoc='';
        resultsDir='';
        covariateIsCol = 1;
        targetIsCol = 1;
        kernelArrApp = {};
        kernelNameArrApp = {};
        varNames = {};
        setFlag = [];
        sourceApp = '';
        min_cov = [];
        max_cov = [];
        avg_cov = [];
        dev_cov = [];
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sourceApp, covariateDataFile, targetDataFile, priorTemplateFile, kernelTemplateFile, resultsDir, covariateIsCol, targetIsCol)
            
            app.sourceApp = sourceApp;
            app.covariateDataFile = covariateDataFile;
            app.CovariatedatafileEditField.Value = app.covariateDataFile;      
            app.targetDataFile = targetDataFile;
            app.TargetdatafileEditField.Value = app.targetDataFile;
            app.priorTemplateFile = priorTemplateFile;
            app.PriortemplatefileEditField.Value = app.priorTemplateFile; 
            app.kernelTemplateFile = kernelTemplateFile;
            app.KerneltemplatefileEditField.Value = app.kernelTemplateFile;
            app.resultsDir = resultsDir;
            app.ResultsdirectorylocationEditField.Value = app.resultsDir;
            app.covariateIsCol = covariateIsCol;
            
            if (app.covariateIsCol == 1)
                app.CovariateOrderDropDown.Value = 'Column vector';
            else
                app.CovariateOrderDropDown.Value = 'Row vector';
            end
            
            app.targetIsCol = targetIsCol;
            
            if (app.targetIsCol == 1)
                app.TargetOrderDropDown.Value = 'Column vector';
            else
                app.TargetOrderDropDown.Value = 'Row vector';
            end
            sourceApp.LonGPUIFigure.Visible = 'off';
        end

        % Button pushed function: CovariateSetButton
        function CovariateSetButtonPushed(app, event)
            
           app.FileDetailsUIFigure.Visible = 'off';
           [FileName,PathName,~] = uigetfile({'*.csv;*.txt'},'Choose covariate file');
           temp = strcat(PathName, FileName);
           app.FileDetailsUIFigure.Visible = 'on';
           
           if (any(isspace(temp)))
               app.CovariatedatafileEditFieldLabel.FontColor = 'red';
               uialert(app.FileDetailsUIFigure,'Spaces in file path not allowed.','Error in file path');
           else              
                app.CovariatedatafileEditFieldLabel.FontColor = 'black';
                app.CovariatedatafileEditField.Value = strcat(PathName, FileName);
           end
        end

        % Button pushed function: TargetSetButton
        function TargetSetButtonPushed(app, event)
           app.FileDetailsUIFigure.Visible = 'off';
           [FileName,PathName,~] = uigetfile({'*.csv;*.txt'},'Choose target file');
           temp = strcat(PathName, FileName);
           app.FileDetailsUIFigure.Visible = 'on';
           
           if (any(isspace(temp)))
               app.TargetdatafileEditFieldLabel.FontColor = 'red';
               uialert(app.FileDetailsUIFigure,'Spaces in file path not allowed.','Error in file path');
           else
                app.TargetdatafileEditFieldLabel.FontColor = 'black';
                app.TargetdatafileEditField.Value = strcat(PathName, FileName);
           end
        end

        % Button pushed function: PriorSetButton
        function PriorSetButtonPushed(app, event)
           app.FileDetailsUIFigure.Visible = 'off';
           [FileName,PathName,~] = uigetfile({'*.csv;*.txt'},'Choose prior template file');
           temp = strcat(PathName, FileName);
           app.FileDetailsUIFigure.Visible = 'on';
           
           if (any(isspace(temp)))
               app.PriortemplatefileEditFieldLabel.FontColor = 'red';
               uialert(app.FileDetailsUIFigure,'Spaces in file path not allowed.','Error in file path');
           else
                app.PriortemplatefileEditFieldLabel.FontColor = 'black';
                app.PriortemplatefileEditField.Value = strcat(PathName, FileName);
           end
        end

        % Button pushed function: KernelSetButton
        function KernelSetButtonPushed(app, event)
           app.FileDetailsUIFigure.Visible = 'off';
           [FileName,PathName,~] = uigetfile({'*.csv;*.txt'},'Choose kernel template file');
           temp = strcat(PathName, FileName);
           app.FileDetailsUIFigure.Visible = 'on';
           if (any(isspace(temp)))
               app.KerneltemplatefileEditFieldLabel.FontColor = 'red';
               uialert(app.FileDetailsUIFigure,'Spaces in file path not allowed.','Error in file path');
           else
                app.KerneltemplatefileEditFieldLabel.FontColor = 'black';
                app.KerneltemplatefileEditField.Value = strcat(PathName, FileName);
           end
        end

        % Button pushed function: ResultSetButton
        function ResultSetButtonPushed(app, event)
           app.FileDetailsUIFigure.Visible = 'off';
           directoryName = uigetdir('','Choose destination for results');
           app.FileDetailsUIFigure.Visible = 'on';
           if (any(isspace(directoryName)))
               app.ResultsdirectorylocationEditFieldLabel.FontColor = 'red';
               uialert(app.FileDetailsUIFigure,'Spaces in file path not allowed.','Error in file path');
           else
                app.ResultsdirectorylocationEditFieldLabel.FontColor = 'black';
                app.ResultsdirectorylocationEditField.Value = directoryName;
           end
       
        end

        % Button pushed function: ProcessfilesButton
        function ProcessfilesButtonPushed(app, event)
            flag = 0;
            % update values
            app.covariateDataFile = app.CovariatedatafileEditField.Value;
            app.targetDataFile = app.TargetdatafileEditField.Value;
            app.priorTemplateFile = app.PriortemplatefileEditField.Value;
            app.kernelTemplateFile = app.KerneltemplatefileEditField.Value;
            app.resultsDir = app.ResultsdirectorylocationEditField.Value;
            
            % File check
           if(strcmp(app.covariateDataFile, ''))
               app.CovariatedatafileEditFieldLabel.FontColor = 'red';
               flag = 1;
           end
           
           if(strcmp(app.targetDataFile, ''))
               app.TargetdatafileEditFieldLabel.FontColor = 'red';
               flag = 1;
           end
           
           if(strcmp(app.priorTemplateFile, ''))
               app.PriortemplatefileEditFieldLabel.FontColor = 'red';
               flag = 1;
           end
           
           if(strcmp(app.kernelTemplateFile, ''))
               app.KerneltemplatefileEditFieldLabel.FontColor = 'red';
               flag = 1;
           end
           
           if(strcmp(app.resultsDir, ''))
               app.ResultsdirectorylocationEditFieldLabel.FontColor = 'red';
               flag = 1;
           end
           
           if (flag == 1)
                uialert(app.FileDetailsUIFigure,'Please provide all files','File(s) Not Provided');
                return;
           end
           
           % file check complete
           if (strcmp(app.CovariateOrderDropDown.Value, 'Column vector'))
               app.covariateIsCol = 1;
           else
               app.covariateIsCol = 0;
           end
           
           if (strcmp(app.TargetOrderDropDown.Value, 'Column vector'))
               app.targetIsCol = 1;
           else
               app.targetIsCol = 0;
           end
           
           % perform extraction
           handle_X = importdata(app.covariateDataFile,'\t');
           try
               if (app.covariateIsCol == 1)
                    app.varNames = handle_X.colheaders;
                    X = handle_X.data;
               else
                    app.varNames = handle_X.rowheaders';
                    X = handle_X.data';
               end
           catch ME
               uialert(app.FileDetailsUIFigure,'Check if covariates are row/column matrix','Header Not Found');
               return;
           end
           app.min_cov = min(handle_X.data);
           app.max_cov = max(handle_X.data);
           app.avg_cov = nanmean(handle_X.data);
           app.dev_cov = nanstd(handle_X.data);
           handle_Y = importdata(app.targetDataFile, '\t');
           try
               if (app.targetIsCol == 1)
                   targetNames = handle_Y.colheaders;
                   y = handle_Y.data;
               else
                   targetNames = handle_Y.rowheaders';
                   y = handle_Y.data';
               end
           catch ME
               uialert(app.FileDetailsUIFigure,'Check if target is row/column matrix','Header Not Found');
               return;
           end
           nSampleX = size(X,1);
           nSampleY = size(y,1);
           if (nSampleX ~= nSampleY)
               msg = sprintf('Number of samples in X (= %d) and y (= %d) do not agree.',nSampleX, nSampleY);
               uialert(app.FileDetailsUIFigure,msg,'Error in input data');
               return;
           end
           
           % extract Kernel information
           try 
               priorArr = parsePrior(app.priorTemplateFile);
           catch ME
               uialert(app.FileDetailsUIFigure,'Unable to parse the prior template. Please check the syntax.','Invalid Prior Template');
               return;
           end
           try
               [kernelArr, kernelInterArr, kernelNameArr] = parseKernel(app.kernelTemplateFile,...
                   priorArr);
           catch ME
               uialert(app.FileDetailsUIFigure,'Unable to parse the kernel template. Please check the syntax.','Invalid Kernel Template');
               return;
           end
           
          % app.SelectcovariateListBox.Items = {};
           items = {};
           for i = 1:length(app.varNames)
                items{end+1} = app.varNames{i};
           end
           %app.SelectcovariateListBox.Items = items;
           app.setFlag = zeros(length(items),1);
           app.kernelArrApp = kernelArr;
           app.kernelNameArrApp = kernelNameArr;
           
           file_callback(app.sourceApp, app.covariateDataFile, app.targetDataFile, app.priorTemplateFile, ...
                app.kernelTemplateFile, app.resultsDir, app.covariateIsCol, app.targetIsCol, app.kernelArrApp, ...
                app.kernelNameArrApp, app.varNames, app.setFlag, app.min_cov, app.max_cov, app.avg_cov, app.dev_cov)
            delete(app);
           
        end

        % Value changed function: CovariatedatafileEditField
        function CovariatedatafileEditFieldValueChanged(app, event)
            value = app.CovariatedatafileEditField.Value;
            app.CovariatedatafileEditField.FontColor = 'black';
        end

        % Value changed function: TargetdatafileEditField
        function TargetdatafileEditFieldValueChanged(app, event)
            value = app.TargetdatafileEditField.Value;
            app.TargetdatafileEditField.FontColor = 'black';
        end

        % Value changed function: PriortemplatefileEditField
        function PriortemplatefileEditFieldValueChanged(app, event)
            value = app.PriortemplatefileEditField.Value;
            app.PriortemplatefileEditField.FontColor = 'black';
        end

        % Value changed function: KerneltemplatefileEditField
        function KerneltemplatefileEditFieldValueChanged(app, event)
            value = app.KerneltemplatefileEditField.Value;
            app.KerneltemplatefileEditField.FontColor = 'black';
        end

        % Value changed function: ResultsdirectorylocationEditField
        function ResultsdirectorylocationEditFieldValueChanged(app, event)
            value = app.ResultsdirectorylocationEditField.Value;
            app.ResultsdirectorylocationEditField.FontColor = 'black';
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            back_callback(app.sourceApp);
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FileDetailsUIFigure = uifigure;
            app.FileDetailsUIFigure.Name = 'File Details';
            app.DatafiledetailsPanel = uipanel(app.FileDetailsUIFigure);
            app.DatafiledetailsPanel.Title = 'Data file details';
            app.CovariatedatafileEditFieldLabel = uilabel(app.DatafiledetailsPanel);
            app.CovariatedatafileEditFieldLabel.Text = 'Covariate data file:';
            app.CovariatedatafileEditField = uieditfield(app.DatafiledetailsPanel, 'text');
            app.CovariatedatafileEditField.ValueChangedFcn = createCallbackFcn(app, @CovariatedatafileEditFieldValueChanged, true);
            app.CovariatedatafileEditField.Value = './X.tab.txt';
            app.TargetdatafileEditFieldLabel = uilabel(app.DatafiledetailsPanel);
            app.TargetdatafileEditFieldLabel.Text = 'Target data file:';
            app.TargetdatafileEditField = uieditfield(app.DatafiledetailsPanel, 'text');
            app.TargetdatafileEditField.ValueChangedFcn = createCallbackFcn(app, @TargetdatafileEditFieldValueChanged, true);
            app.TargetdatafileEditField.Value = './Y.tab.txt';
            app.PriortemplatefileEditFieldLabel = uilabel(app.DatafiledetailsPanel);
            app.PriortemplatefileEditFieldLabel.Text = 'Prior template file:';
            app.PriortemplatefileEditField = uieditfield(app.DatafiledetailsPanel, 'text');
            app.PriortemplatefileEditField.ValueChangedFcn = createCallbackFcn(app, @PriortemplatefileEditFieldValueChanged, true);
            app.PriortemplatefileEditField.Value = './conf/prior.template.txt';
            app.KerneltemplatefileEditFieldLabel = uilabel(app.DatafiledetailsPanel);
            app.KerneltemplatefileEditFieldLabel.Text = 'Kernel template file:';
            app.KerneltemplatefileEditField = uieditfield(app.DatafiledetailsPanel, 'text');
            app.KerneltemplatefileEditField.ValueChangedFcn = createCallbackFcn(app, @KerneltemplatefileEditFieldValueChanged, true);
            app.KerneltemplatefileEditField.Value = './conf/kernel.template.txt';
            app.CovariateSetButton = uibutton(app.DatafiledetailsPanel, 'push');
            app.CovariateSetButton.ButtonPushedFcn = createCallbackFcn(app, @CovariateSetButtonPushed, true);
            app.CovariateSetButton.Text = 'Set';
            app.TargetSetButton = uibutton(app.DatafiledetailsPanel, 'push');
            app.TargetSetButton.ButtonPushedFcn = createCallbackFcn(app, @TargetSetButtonPushed, true);
            app.TargetSetButton.Text = 'Set';
            app.PriorSetButton = uibutton(app.DatafiledetailsPanel, 'push');
            app.PriorSetButton.ButtonPushedFcn = createCallbackFcn(app, @PriorSetButtonPushed, true);
            app.PriorSetButton.Text = 'Set';
            app.KernelSetButton = uibutton(app.DatafiledetailsPanel, 'push');
            app.KernelSetButton.ButtonPushedFcn = createCallbackFcn(app, @KernelSetButtonPushed, true);
            app.KernelSetButton.Text = 'Set';
            app.ProcessfilesButton = uibutton(app.DatafiledetailsPanel, 'push');
            app.ProcessfilesButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessfilesButtonPushed, true);
            app.ProcessfilesButton.Text = 'Process files';
            app.OrderDropDownLabel = uilabel(app.DatafiledetailsPanel);
            app.OrderDropDownLabel.Text = 'Order:';
            app.TargetOrderDropDown = uidropdown(app.DatafiledetailsPanel);
            app.TargetOrderDropDown.Items = {'Column vector', 'Row vector'};
            app.TargetOrderDropDown.Value = 'Column vector';
            app.OrderDropDown_2Label = uilabel(app.DatafiledetailsPanel);
            app.OrderDropDown_2Label.Text = 'Order:';
            app.CovariateOrderDropDown = uidropdown(app.DatafiledetailsPanel);
            app.CovariateOrderDropDown.Items = {'Column vector', 'Row vector'};
            app.CovariateOrderDropDown.Value = 'Column vector';
            app.ResultsdirectorylocationEditFieldLabel = uilabel(app.DatafiledetailsPanel);
            app.ResultsdirectorylocationEditFieldLabel.Text = 'Results directory location:';
            app.ResultsdirectorylocationEditField = uieditfield(app.DatafiledetailsPanel, 'text');
            app.ResultsdirectorylocationEditField.ValueChangedFcn = createCallbackFcn(app, @ResultsdirectorylocationEditFieldValueChanged, true);
            app.ResultsdirectorylocationEditField.Value = './output.directory';
            app.ResultSetButton = uibutton(app.DatafiledetailsPanel, 'push');
            app.ResultSetButton.ButtonPushedFcn = createCallbackFcn(app, @ResultSetButtonPushed, true);
            app.ResultSetButton.Text = 'Set';
            app.BackButton = uibutton(app.DatafiledetailsPanel, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
        end
    end

    methods (Access = public)

        % Construct app
        function app = file_window(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FileDetailsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FileDetailsUIFigure)
        end
    end
end