classdef init_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LonGPUIFigure                   matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SetUpTab                        matlab.ui.container.Tab
        LonGPInitialisationPanel        matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        Step1Label                      matlab.ui.control.Label
        SetKernelsButton                matlab.ui.control.Button
        SettherequiredfilelocationsLampLabel  matlab.ui.control.Label
        SettherequiredfilelocationsLamp  matlab.ui.control.Lamp
        Step2Label                      matlab.ui.control.Label
        SetkernelsforthecovariatesLabel  matlab.ui.control.Label
        SetkernelsforthecovariatesLamp  matlab.ui.control.Lamp
        Step3Label                      matlab.ui.control.Label
        SetparametersforexecutionLampLabel  matlab.ui.control.Label
        SetparametersforexecutionLamp   matlab.ui.control.Lamp
        Step4Label                      matlab.ui.control.Label
        OptionsfortestdataLampLabel     matlab.ui.control.Label
        OptionsfortestdataLamp          matlab.ui.control.Lamp
        SetFilesButton                  matlab.ui.control.Button
        SettingsButton                  matlab.ui.control.Button
        TestOptionsButton               matlab.ui.control.Button
        PlotOptionsButton               matlab.ui.control.Button
        OptionsforplotgenerationLampLabel  matlab.ui.control.Label
        OptionsforplotgenerationLamp    matlab.ui.control.Lamp
        OptionalLabel                   matlab.ui.control.Label
        ParameterFileGenerationPanel    matlab.ui.container.Panel
        SetButton                       matlab.ui.control.Button
        GenerateButton                  matlab.ui.control.Button
        LocationtogeneratefileEditFieldLabel  matlab.ui.control.Label
        LocationtogeneratefileEditField  matlab.ui.control.EditField
        Label_2                         matlab.ui.control.Label
        ResultsTab                      matlab.ui.container.Tab
        PostProcessingPanel             matlab.ui.container.Panel
        GenerateSpreadsheetButton       matlab.ui.control.Button
        SetButton_3                     matlab.ui.control.Button
        ResultsdirectorylocationEditFieldLabel  matlab.ui.control.Label
        ResultsdirectorylocationEditField  matlab.ui.control.EditField
        CovariateindexforxaxisLabel     matlab.ui.control.Label
        CovariateindexforxaxisEditField  matlab.ui.control.NumericEditField
        CovariateindexforcolouringindividualsEditFieldLabel  matlab.ui.control.Label
        CovariateindexforcolouringindividualsEditField  matlab.ui.control.NumericEditField
        GeneratePlotsButton             matlab.ui.control.Button
        CovariateindicesofcomponentstobeplottedEditFieldLabel  matlab.ui.control.Label
        CovariateindicesofcomponentstobeplottedEditField  matlab.ui.control.EditField
        Label_3                         matlab.ui.control.Label
    end


    properties (Access = private)
        
        % Specify public properties
        covariateDataFile='./X.tab.txt';
        targetDataFile='./Y.tab.txt';
        priorTemplateFile='./conf/prior.template.txt';
        kernelTemplateFile='./conf/kernel.template.txt' 
        resultsDir='./output.directory';
        covariateIsCol = 1;
        targetIsCol = 1;
        kernelArrApp = {};
        kernelNameArrApp = {};
        varNames = {};
        setFlag = [];
        kernelSelectedArr = struct('name','', 'period','','a',''...
            ,'b','','c','', 'interaction','','selected','');
        nConVar = 0;
        nBinVar = 0;
        omittedInteractions='';
        loocvThresh='0.8';
        scvThresh='0.95';
        bootstrap='20000';
        minVar='0.01';
        maxRunTime='Inf';
        maxIdleTime='120';
        idxAgeCov='';
        rawdataFile='';
        rawTestFile='';
        idxXAxis=0;
        idxColour=0;
        genTest=0;
        genFig=0;
        complete = [0, 0, 0, 0];
        generateLoc = './conf';
        contArr = [];
        minCov = [];
        maxCov = [];
        avgCov = [];
        devCov = [];

    end

    methods (Access = public)
    
        function file_callback(app, covariateDataFile, targetDataFile, priorTemplateFile, ...
                kernelTemplateFile, resultsDir, covariateIsCol, targetIsCol, kernelArrApp, ...
                kernelNameArrApp, varNames, setFlag, minCov, maxCov, avgCov, devCov)
            
            % Call back function for file_window.mlapp
            app.LonGPUIFigure.Visible = 'on';
            app.covariateDataFile = covariateDataFile;
            app.targetDataFile = targetDataFile;
            app.priorTemplateFile = priorTemplateFile;
            app.kernelTemplateFile = kernelTemplateFile;
            app.resultsDir = resultsDir;
            app.covariateIsCol = covariateIsCol;
            app.targetIsCol = targetIsCol;
            app.kernelArrApp = kernelArrApp;
            app.kernelNameArrApp = kernelNameArrApp;
            app.varNames = varNames;
            app.setFlag = setFlag;
            app.minCov = minCov;
            app.maxCov = maxCov;
            app.avgCov = avgCov;
            app.devCov = devCov;
            app.rawdataFile = strcat(app.resultsDir,'/rawdata.filled.txt');
            app.generateLoc = app.resultsDir;
            app.LocationtogeneratefileEditField.Value = app.generateLoc;
            
            app.complete = [1, 0, 0, 0];
            app.nConVar = 0;
            app.nBinVar = 0;
            app.SettherequiredfilelocationsLamp.Color = [0.47 0.67 0.19];
            app.SetkernelsforthecovariatesLamp.Color = [0.80 0.80 0.80];
            app.SetparametersforexecutionLamp.Color = [0.80 0.80 0.80];
            app.OptionsfortestdataLamp.Color = [0.80 0.80 0.80];
            
            app.kernelSelectedArr = struct('name','', 'period','','a',''...
            ,'b','','c','', 'interaction','','selected','');
            app.contArr = zeros(length(varNames),1);
            
            summary_string = 'All files read.';
            summary_string = strcat(summary_string, sprintf('\n\nCovariates:'));
            for i = 1:length(varNames)
                summary_string = strcat(summary_string, sprintf(' %s',varNames{i}));
            end
            summary_string = strcat(summary_string, sprintf('\n\nTarget data file: %s',app.targetDataFile));
            summary_string = strcat(summary_string, sprintf('\nCovariate data file: %s', app.covariateDataFile));
            summary_string = strcat(summary_string, sprintf('\nPrior file: %s', app.priorTemplateFile));
            summary_string = strcat(summary_string, sprintf('\nKernel file: %s', app.kernelTemplateFile));
            summary_string = strcat(summary_string, sprintf('\nResult directory: %s', app.resultsDir));
            
            uialert(app.LonGPUIFigure,...
                        summary_string,'Summary', 'Icon', 'success');
         
        end
        
        function kernel_window_callback(app, kernelSelectedArr, setFlag, nConVar, nBinVar, contArr)
            
            % Call back function for kernel_dialog.mlapp
            
            app.LonGPUIFigure.Visible = 'on';          
            app.kernelSelectedArr = kernelSelectedArr;
            app.setFlag = setFlag;    
            app.nConVar = nConVar;
            app.nBinVar = nBinVar;
            app.contArr = contArr;
             
           summary_string = sprintf('Summary of kernels set:');
           for i = 1:length(app.varNames)
               cur_ker = app.kernelSelectedArr(i);
               cur_type = '';
               if app.contArr(i) == 0
                   cur_type = 'Continuous';
               else
                   cur_type = 'Discrete';
               end
               str_sum = sprintf('\n%d. %s -- %s (%s)', i, app.varNames{i}, cur_ker.name, cur_type);
               summary_string = strcat(summary_string, str_sum);
           end
            
           uialert(app.LonGPUIFigure,...
                        summary_string,'Summary', 'Icon', 'success');
            
            app.SetkernelsforthecovariatesLamp.Color = [0.47 0.67 0.19];
            app.complete = [1, 1, 0, 0];
        end
        
        function settings_window_callback(app, omittedInteractions, loocvThresh, scvThresh, bootstrap, minVar, maxRunTime, maxIdleTime)
            
            % Call back function for settings_window.mlapp
            
            app.LonGPUIFigure.Visible = 'on';   
            app.omittedInteractions = omittedInteractions;
            app.loocvThresh = loocvThresh;
            app.scvThresh = scvThresh;
            app.bootstrap = bootstrap;
            app.minVar = minVar;
            app.maxRunTime = maxRunTime;
            app.maxIdleTime = maxIdleTime;    
            app.SetparametersforexecutionLamp.Color = [0.47 0.67 0.19];
            app.complete = [1, 1, 1, 0];  
        end
        
        function misc_window_callback(app,idxAgeCov, rawdataFile, rawTestFile, genTest)
            
            % Call back function for misc_window.mlapp
            
            app.LonGPUIFigure.Visible = 'on';  
            app.idxAgeCov = idxAgeCov;
            app.rawdataFile = rawdataFile;
            app.rawTestFile = rawTestFile;
            app.genTest = genTest;      
            app.OptionsfortestdataLamp.Color = [0.47 0.67 0.19];
            app.complete = [1, 1, 1, 1];
        end
        
        function image_window_callback(app, idxXAxis, idxColour, genFig)
            
            % Call back function for plot_window.mlapp 
            
            app.LonGPUIFigure.Visible = 'on';  
            app.idxXAxis = idxXAxis;
            app.idxColour = idxColour;
            app.genFig = genFig;
            app.OptionsforplotgenerationLamp.Color = [0.47 0.67 0.19];
        end
        
        function kernel_fail(app, flag)
            
            % function for handling improper kernel specification.
            
            app.LonGPUIFigure.Visible = 'on';
            if flag == 1
                uialert(app.LonGPUIFigure,...
                        'id covariate must be Mask/Binary','Input Error');
            elseif flag == 2
                uialert(app.LonGPUIFigure,...
                        'At least one continuous covariate is required.', 'Input Error'); 
            elseif flag == 3
                uialert(app.LonGPUIFigure,...
                        'All continuous covariates must come before binary covariates', 'Input Error');
            elseif flag == 4
                uialert(app.LonGPUIFigure,...
                        'All continuous covariates must come before binary covariates','Input Error');
                    
            elseif flag == 5
                uialert(app.LonGPUIFigure,...
                        'Last covariate must be id.','Input Error');
            end   
            app.SetkernelsforthecovariatesLamp.Color = [1 0 0];
            app.complete = [1, 0, 0, 0];
        end
        
        function back_callback(app)
            
            % Call back when 'back' button is pressed.
            
            app.LonGPUIFigure.Visible = 'on';
        end
        
    end


    methods (Access = private)

        % Button pushed function: SetFilesButton
        function SetFilesButtonPushed(app, event)
            file_window(app, app.covariateDataFile, app.targetDataFile, ...
                app.priorTemplateFile, app.kernelTemplateFile, app.resultsDir, ...
                app.covariateIsCol, app.targetIsCol);
        end

        % Button pushed function: SetKernelsButton
        function SetKernelsButtonPushed(app, event)
            if (app.complete(1) == 0)
                uialert(app.LonGPUIFigure,'Input files not processed. Please complete Step 1.','Unprocessed files');
                return;
            end
            kernel_dialog(app, app.varNames, app.setFlag, app.kernelArrApp, ...
                app.kernelNameArrApp, app.kernelSelectedArr, ...
                app.nConVar, app.nBinVar, app.contArr, app.minCov, app.maxCov, app.avgCov, app.devCov);
        end

        % Button pushed function: SettingsButton
        function SettingsButtonPushed(app, event)
            if (app.complete(1) == 0)
                uialert(app.LonGPUIFigure,'Input files not processed. Please complete Step 1.','Unprocessed files');
                return;
            end
            
            if (app.complete(2) == 0)
                uialert(app.LonGPUIFigure,'Kernels not set for the covariates. Please complete Step 2.','Kernels not set');
                return;
            end
            settings_window(app, app.omittedInteractions, app.loocvThresh, ...
                app.scvThresh, app.bootstrap, app.minVar, app.maxRunTime, app.maxIdleTime);
        end

        % Button pushed function: TestOptionsButton
        function TestOptionsButtonPushed(app, event)
            if (app.complete(1) == 0)
                uialert(app.LonGPUIFigure,'Input files not processed. Please complete Step 1.','Unprocessed files');
                return;
            end
            
            if (app.complete(2) == 0)
                uialert(app.LonGPUIFigure,'Kernels not set for the covariates. Please complete Step 2.','Kernels not set');
                return;
            end
            
            if (app.complete(3) == 0)
                uialert(app.LonGPUIFigure,'Execution parameters are not set. Please complete Step 3.','Parameters not set');
                return;
            end
            selection = uiconfirm(app.LonGPUIFigure,'Test data is only meant for plotting smooth curves. Do you wish to continue?','Confirm test data generation',...
                        'Icon','warning', 'Options',{'Confirm','Cancel'},...
           'DefaultOption',2,'CancelOption',2);
            if strcmp(selection, 'Confirm')        
                misc_window(app, app.idxAgeCov, app.rawdataFile, app.rawTestFile, app.genTest) 
            end
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
           app.LonGPUIFigure.Visible = 'off';
           directoryName = uigetdir('','Choose destination for template');
           app.LonGPUIFigure.Visible = 'on';
           app.LocationtogeneratefileEditFieldLabel.FontColor = 'black';
           app.LocationtogeneratefileEditField.Value = directoryName;
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            flag = 0;
            app.generateLoc = app.LocationtogeneratefileEditField.Value;
            if(strcmp(app.generateLoc, ''))
               app.LocationtogeneratefileEditFieldLabel.FontColor = 'red';
               flag = 1;
            end
            if (flag == 1)
                uialert(app.LonGPUIFigure,'Please fill and verify all mandatory fields.','Incomplete specification');
                return;
            end
            
           dest = strcat(app.generateLoc, '/input.para.txt');
           fid = fopen(dest,'wt');
           fprintf(fid,'inputX=%s\tisCol=%d\n',app.covariateDataFile, app.covariateIsCol);
           fprintf(fid,'inputY=%s\tisCol=%d\n',app.targetDataFile, app.targetIsCol);
           fprintf(fid,'resDir=%s\n',app.resultsDir);
           fprintf(fid,'nConVar=%s\n',num2str(app.nConVar));
           fprintf(fid,'nBinVar=%s\n',num2str(app.nBinVar)); 
           fprintf(fid,'priorFile=%s\n',app.priorTemplateFile);
           fprintf(fid,'kernelFile=%s\n',app.kernelTemplateFile);
           
           %%% Kernels %%
           % mapping
           items = {};
           for i = 1:length(app.kernelNameArrApp)
                switch app.kernelNameArrApp{i}
                    case 'se'
                        items{end+1} = 'Squared Exponential';
                    case 'ns'
                        items{end+1} = 'Non-stationery';
                    case 'pe'
                        items{end+1} = 'Periodic';
                    case 'co'
                        items{end+1} = 'Constant';
                    case 'ca'
                        items{end+1} = 'Categorical';
                    case 'bi'
                        items{end+1} = 'Mask/Binary';
                end
           end
           
           for i = 1:length(app.kernelSelectedArr)
               idx = find(contains(items, app.kernelSelectedArr(i).name));
               if strcmp(app.kernelSelectedArr(i).name, 'Non-stationery')
                   fprintf(fid,'varIndex=%d\tvarName=%s\tinteraction=%d\tselected=%d\tkernelType=%d\ta=%f\tb=%f\tc=%f\n',...
                       i,app.varNames{i},app.kernelSelectedArr(i).interaction, app.kernelSelectedArr(i).selected, idx, ...
                       app.kernelSelectedArr(i).a, app.kernelSelectedArr(i).b, app.kernelSelectedArr(i).c);
               elseif strcmp(app.kernelSelectedArr(i).name, 'Periodic')
                   fprintf(fid,'varIndex=%d\tvarName=%s\tinteraction=%d\tselected=%d\tkernelType=%d\tperiod=%s\n',...
                       i,app.varNames{i},app.kernelSelectedArr(i).interaction, app.kernelSelectedArr(i).selected, idx, ...
                       app.kernelSelectedArr(i).period);
               else
                    fprintf(fid,'varIndex=%d\tvarName=%s\tinteraction=%d\tselected=%d\tkernelType=%d\n',...
                       i,app.varNames{i},app.kernelSelectedArr(i).interaction, app.kernelSelectedArr(i).selected, idx);
               end
           end
           
           if(~isempty(app.omittedInteractions))
                fprintf(fid,'delInterTerms=%s\n',app.omittedInteractions);
           end
           
           fprintf(fid,'looCutOff=%s\n',app.loocvThresh);
           fprintf(fid, 'scvCutOff=%s\n',app.scvThresh);
           fprintf(fid, 'nBBSample=%s\n', app.bootstrap);
           fprintf(fid, 'comVarCutOff=%s\n', app.minVar);
           fprintf(fid, 'maxRunTime=%s\n', app.maxRunTime);
           fprintf(fid, 'maxIdleTime=%s\n', app.maxIdleTime);
           
           if app.genTest == 1
               % test
               fprintf(fid, "genTestX=%d\tageInd=%s\tRawXFilledFile='%s'\ttestXFilledFile='%s'\n", ...
                   app.genTest, app.idxAgeCov, app.rawdataFile, app.rawTestFile);
           end
           
           if app.genFig == 1
                % plot
                fprintf(fid, 'plotFigure=%d\txInd=%d\tcolorInd=%d\n', app.genFig, app.idxXAxis, app.idxColour);   
           end
           fclose(fid);
           text = strcat('input.para.txt generated!\n\nLocation: ', dest);
           uialert(app.LonGPUIFigure,sprintf(text),'Completed', 'Icon', 'success');    
        end

        % Button pushed function: PlotOptionsButton
        function PlotOptionsButtonPushed(app, event)
            if (app.complete(1) == 0)
                uialert(app.LonGPUIFigure,'Input files not processed. Please complete Step 1.','Unprocessed files');
                return;
            end
            
            if (app.complete(2) == 0)
                uialert(app.LonGPUIFigure,'Kernels not set for the covariates. Please complete Step 2.','Kernels not set');
                return;
            end
            
            if (app.complete(3) == 0)
                uialert(app.LonGPUIFigure,'Execution parameters are not set. Please complete Step 3.','Parameters not set');
                return;
            end
            
            app.LonGPUIFigure.Visible = 'off';
            plot_window(app, app.idxXAxis, app.idxColour, app.genFig, app.kernelSelectedArr, app.varNames, app.nConVar, app.nBinVar, app.contArr);
        end

        % Callback function
        function SetButton_2Pushed(app, event)
           app.LonGPUIFigure.Visible = 'off';
           directoryName = uigetdir('','Choose location for excel file');
           app.LonGPUIFigure.Visible = 'on';
           app.SummaryfilelocationEditFieldLabel.FontColor = 'black';
           app.SummaryfilelocationEditField.Value = directoryName;
        end

        % Button pushed function: SetButton_3
        function SetButton_3Pushed(app, event)
           app.LonGPUIFigure.Visible = 'off';
           directoryName = uigetdir('','Specify destination chosen for results');
           app.LonGPUIFigure.Visible = 'on';
           file_check = sprintf('%s%sResults',directoyName, filesep);
           if (any(isspace(directoryName)))
               app.ResultsdirectorylocationEditFieldLabel.FontColor = 'red';
               uialert(app.FileDetailsUIFigure,'Spaces in file path not allowed.','Error in file path');   
           elseif (exist(file_check, 'dir') ~= 7)
               app.ResultsdirectorylocationEditFieldLabel.FontColor = 'red';   
               uialert(app.FileDetailsUIFigure,'Results are not yet generated. Please run the computations first.','Results not generated');
           else
                app.ResultsdirectorylocationEditFieldLabel.FontColor = 'black';
                app.ResultsdirectorylocationEditField.Value = directoryName;
           end
        end

        % Value changed function: ResultsdirectorylocationEditField
        function ResultsdirectorylocationEditFieldValueChanged(app, event)
            value = app.ResultsdirectorylocationEditField.Value;
            app.ResultsdirectorylocationEditField.FontColor = 'black';
            app.ResultsdirectorylocationEditFieldLabel.FontColor = 'black';
        end

        % Button pushed function: GenerateSpreadsheetButton
        function GenerateSpreadsheetButtonPushed(app, event)
            parentDir = app.ResultsdirectorylocationEditField.Value;
            collectResult(parentDir);
            file_loc = sprintf('%s%sfinalResult.xlsx',parentDir,filesep);
            text = sprintf('Summary file generated.\nFile location: %s',file_loc);
            uialert(app.LonGPUIFigure,text,'Summary generated', 'Icon', 'success');
        end

        % Button pushed function: GeneratePlotsButton
        function GeneratePlotsButtonPushed(app, event)
            resDir = app.ResultsdirectorylocationEditField.Value;
            xInd = app.CovariateindexforxaxisEditField.Value;
            colourInd = app.CovariateindexforcolouringindividualsEditField.Value;
            componentInd = str2num(app.CovariateindicesofcomponentstobeplottedEditField.Value);
            genComPlots(resDir, xInd, colourInd, componentInd);
            uialert(app.LonGPUIFigure,'Plots successfully generated.','Plots generated', 'Icon', 'success');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LonGPUIFigure = uifigure;
            app.LonGPUIFigure.Name = 'LonGP';
            app.TabGroup = uitabgroup(app.LonGPUIFigure);
            app.SetUpTab = uitab(app.TabGroup);
            app.SetUpTab.Title = 'Set Up';
            app.LonGPInitialisationPanel = uipanel(app.SetUpTab);
            app.LonGPInitialisationPanel.Title = 'LonGP Initialisation';
            app.Label = uilabel(app.LonGPInitialisationPanel);
            app.Label.Text = '';
            app.Step1Label = uilabel(app.LonGPInitialisationPanel);
            app.Step1Label.Text = 'Step 1.';
            app.SetKernelsButton = uibutton(app.LonGPInitialisationPanel, 'push');
            app.SetKernelsButton.ButtonPushedFcn = createCallbackFcn(app, @SetKernelsButtonPushed, true);
            app.SetKernelsButton.Text = 'Set Kernels';
            app.SettherequiredfilelocationsLampLabel = uilabel(app.LonGPInitialisationPanel);
            app.SettherequiredfilelocationsLampLabel.Text = 'Set the required file locations.';
            app.SettherequiredfilelocationsLamp = uilamp(app.LonGPInitialisationPanel);
            app.Step2Label = uilabel(app.LonGPInitialisationPanel);
            app.Step2Label.Text = 'Step 2.';
            app.SetkernelsforthecovariatesLabel = uilabel(app.LonGPInitialisationPanel);
            app.SetkernelsforthecovariatesLabel.Text = 'Set kernels for the covariates.';
            app.SetkernelsforthecovariatesLamp = uilamp(app.LonGPInitialisationPanel);
            app.Step3Label = uilabel(app.LonGPInitialisationPanel);
            app.Step3Label.Text = 'Step 3.';
            app.SetparametersforexecutionLampLabel = uilabel(app.LonGPInitialisationPanel);
            app.SetparametersforexecutionLampLabel.Text = 'Set parameters for execution.';
            app.SetparametersforexecutionLamp = uilamp(app.LonGPInitialisationPanel);
            app.Step4Label = uilabel(app.LonGPInitialisationPanel);
            app.Step4Label.Text = 'Step 4.';
            app.OptionsfortestdataLampLabel = uilabel(app.LonGPInitialisationPanel);
            app.OptionsfortestdataLampLabel.Text = 'Options for test data.';
            app.OptionsfortestdataLamp = uilamp(app.LonGPInitialisationPanel);
            app.SetFilesButton = uibutton(app.LonGPInitialisationPanel, 'push');
            app.SetFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SetFilesButtonPushed, true);
            app.SetFilesButton.Text = 'Set Files';
            app.SettingsButton = uibutton(app.LonGPInitialisationPanel, 'push');
            app.SettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SettingsButtonPushed, true);
            app.SettingsButton.Text = 'Settings';
            app.TestOptionsButton = uibutton(app.LonGPInitialisationPanel, 'push');
            app.TestOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @TestOptionsButtonPushed, true);
            app.TestOptionsButton.Text = 'Test Options';
            app.PlotOptionsButton = uibutton(app.LonGPInitialisationPanel, 'push');
            app.PlotOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotOptionsButtonPushed, true);
            app.PlotOptionsButton.Text = 'Plot Options';
            app.OptionsforplotgenerationLampLabel = uilabel(app.LonGPInitialisationPanel);
            app.OptionsforplotgenerationLampLabel.Text = 'Options for plot generation.';
            app.OptionsforplotgenerationLamp = uilamp(app.LonGPInitialisationPanel);
            app.OptionalLabel = uilabel(app.LonGPInitialisationPanel);
            app.OptionalLabel.Text = '(Optional)';
            app.ParameterFileGenerationPanel = uipanel(app.SetUpTab);
            app.ParameterFileGenerationPanel.Title = 'Parameter File Generation';
            app.SetButton = uibutton(app.ParameterFileGenerationPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.GenerateButton = uibutton(app.ParameterFileGenerationPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.LocationtogeneratefileEditFieldLabel = uilabel(app.ParameterFileGenerationPanel);
            app.LocationtogeneratefileEditFieldLabel.Text = 'Location to generate file:';
            app.LocationtogeneratefileEditField = uieditfield(app.ParameterFileGenerationPanel, 'text');
            app.LocationtogeneratefileEditField.Value = './conf';
            app.Label_2 = uilabel(app.SetUpTab);
            app.Label_2.Text = {'This pane is for performing the initial set-up required to run LonGP. Please perform the initialisation steps '; 'below in the same order and generate the parameter file. The results pane is used to generate a summary '; 'of the results in a spreadsheet and generate plots.'};
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.PostProcessingPanel = uipanel(app.ResultsTab);
            app.PostProcessingPanel.Title = 'Post Processing';
            app.GenerateSpreadsheetButton = uibutton(app.PostProcessingPanel, 'push');
            app.GenerateSpreadsheetButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSpreadsheetButtonPushed, true);
            app.GenerateSpreadsheetButton.Text = 'Generate Spreadsheet';
            app.SetButton_3 = uibutton(app.PostProcessingPanel, 'push');
            app.SetButton_3.ButtonPushedFcn = createCallbackFcn(app, @SetButton_3Pushed, true);
            app.SetButton_3.Text = 'Set';
            app.ResultsdirectorylocationEditFieldLabel = uilabel(app.PostProcessingPanel);
            app.ResultsdirectorylocationEditFieldLabel.Text = 'Results directory location:';
            app.ResultsdirectorylocationEditField = uieditfield(app.PostProcessingPanel, 'text');
            app.ResultsdirectorylocationEditField.ValueChangedFcn = createCallbackFcn(app, @ResultsdirectorylocationEditFieldValueChanged, true);
            app.ResultsdirectorylocationEditField.Value = './results';
            app.CovariateindexforxaxisLabel = uilabel(app.PostProcessingPanel);
            app.CovariateindexforxaxisLabel.Text = 'Covariate index for x-axis:';
            app.CovariateindexforxaxisEditField = uieditfield(app.PostProcessingPanel, 'numeric');
            app.CovariateindexforcolouringindividualsEditFieldLabel = uilabel(app.PostProcessingPanel);
            app.CovariateindexforcolouringindividualsEditFieldLabel.Text = 'Covariate index for colouring individuals:';
            app.CovariateindexforcolouringindividualsEditField = uieditfield(app.PostProcessingPanel, 'numeric');
            app.GeneratePlotsButton = uibutton(app.PostProcessingPanel, 'push');
            app.GeneratePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePlotsButtonPushed, true);
            app.GeneratePlotsButton.Text = 'Generate Plots';
            app.CovariateindicesofcomponentstobeplottedEditFieldLabel = uilabel(app.PostProcessingPanel);
            app.CovariateindicesofcomponentstobeplottedEditFieldLabel.Text = 'Covariate indices of components to be plotted:';
            app.CovariateindicesofcomponentstobeplottedEditField = uieditfield(app.PostProcessingPanel, 'text');
            app.Label_3 = uilabel(app.ResultsTab);
            app.Label_3.Text = {'This pane is used to generate a summary of the results in a spreadsheet and generate plots. The '; 'operations in this pane can only be run after performing the computations using LonGP.'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = init_window

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LonGPUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LonGPUIFigure)
        end
    end
end