classdef misc_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TestdatasettingsUIFigure      matlab.ui.Figure
        TestfiledetailsPanel          matlab.ui.container.Panel
        IndexofagecovariateEditFieldLabel  matlab.ui.control.Label
        IndexofagecovariateEditField  matlab.ui.control.EditField
        RawdatafilewithimputedmissingvaluesEditFieldLabel  matlab.ui.control.Label
        RawdatafilewithimputedmissingvaluesEditField  matlab.ui.control.EditField
        TestdatafilewithimputedmissingvaluesEditFieldLabel  matlab.ui.control.Label
        TestdatafilewithimputedmissingvaluesEditField  matlab.ui.control.EditField
        SetButton_2                   matlab.ui.control.Button
        SetButton_3                   matlab.ui.control.Button
        DoneButton                    matlab.ui.control.Button
        BackButton                    matlab.ui.control.Button
    end


    properties (Access = public)
        
        % Specify public properties
        sourceApp = '';
        idxAgeCov='';
        rawdataFile='';
        rawTestFile='';
        genTest='';
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sourceApp, idxAgeCov, rawdataFile, rawTestFile, genTest)
            app.sourceApp = sourceApp;
            app.idxAgeCov = idxAgeCov;
            app.rawdataFile = rawdataFile;
            app.rawTestFile = rawTestFile;
            app.genTest = genTest;
            app.IndexofagecovariateEditField.Value = num2str(idxAgeCov);
            app.RawdatafilewithimputedmissingvaluesEditField.Value = app.rawdataFile;
          
            if (app.genTest == 1)
  %              app.GeneratetestdataCheckBox.Value = 1;
                app.RawdatafilewithimputedmissingvaluesEditField.Enable = 'on';
                app.TestdatafilewithimputedmissingvaluesEditField.Enable = 'on';
                app.RawdatafilewithimputedmissingvaluesEditFieldLabel.Enable = 'on';
                app.TestdatafilewithimputedmissingvaluesEditFieldLabel.Enable = 'on';
                app.RawdatafilewithimputedmissingvaluesEditField.Value = app.rawdataFile;
                app.TestdatafilewithimputedmissingvaluesEditField.Value = app.rawTestFile;
%             else
%                 app.GeneratetestdataCheckBox.Value = 0;
            end
            sourceApp.LonGPUIFigure.Visible = 'off';
                
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.idxAgeCov = app.IndexofagecovariateEditField.Value;
            app.rawdataFile = app.RawdatafilewithimputedmissingvaluesEditField.Value;
            app.rawTestFile = app.TestdatafilewithimputedmissingvaluesEditField.Value;
            %app.genTest = app.GeneratetestdataCheckBox.Value;
            app.genTest = 1;
            flag = 0;
  
           [~, status] = str2num(app.idxAgeCov);
           if(strcmp(app.idxAgeCov, '') || status == 0 )
               app.IndexofagecovariateEditFieldLabel.FontColor = 'red';
               flag = 1;
           end
           
           if (flag == 1)
                uialert(app.TestdatasettingsUIFigure,'Please fill and verify all mandatory fields.','Incomplete specification');
                return;
           end
           
           misc_window_callback(app.sourceApp, app.idxAgeCov, app.rawdataFile, app.rawTestFile ,app.genTest);
           delete(app);         
        end

        % Callback function
        function GeneratetestdataCheckBoxValueChanged(app, event)
%             value = app.GeneratetestdataCheckBox.Value;
%             if (value == 1)
%                 selection = uiconfirm(app.TestdatasettingsUIFigure,'Test data is only meant for plotting smooth curves. Do you wish to continue?','Confirm test data generation',...
%                         'Icon','warning', 'Options',{'Confirm','Cancel'},...
%            'DefaultOption',2,'CancelOption',2);
%        
%                 if strcmp(selection, 'Cancel')
%                     app.GeneratetestdataCheckBox.Value = 0;
%                     app.RawdatafilewithimputedmissingvaluesEditField.Enable = 'off';
%                     app.TestdatafilewithimputedmissingvaluesEditField.Enable = 'off';
%                     app.RawdatafilewithimputedmissingvaluesEditFieldLabel.Enable = 'off';
%                     app.TestdatafilewithimputedmissingvaluesEditFieldLabel.Enable = 'off';
%                     app.RawdatafilewithimputedmissingvaluesEditField.Value = '';
%                     app.TestdatafilewithimputedmissingvaluesEditField.Value = '';
%                     app.SetButton_2.Enable = 'off';
%                     app.SetButton_3.Enable = 'off';
%                     DoneButtonPushed(app, []);
%                 end
%                 
%                 
%                 app.RawdatafilewithimputedmissingvaluesEditField.Enable = 'on';
%                 app.TestdatafilewithimputedmissingvaluesEditField.Enable = 'on';
%                 app.RawdatafilewithimputedmissingvaluesEditFieldLabel.Enable = 'on';
%                 app.TestdatafilewithimputedmissingvaluesEditFieldLabel.Enable = 'on';
%                 app.SetButton_2.Enable = 'on';
%                 app.SetButton_3.Enable = 'on';
%             else
%                 app.RawdatafilewithimputedmissingvaluesEditField.Enable = 'off';
%                 app.TestdatafilewithimputedmissingvaluesEditField.Enable = 'off';
%                 app.RawdatafilewithimputedmissingvaluesEditFieldLabel.Enable = 'off';
%                 app.TestdatafilewithimputedmissingvaluesEditFieldLabel.Enable = 'off';
%                 app.RawdatafilewithimputedmissingvaluesEditField.Value = '';
%                 app.TestdatafilewithimputedmissingvaluesEditField.Value = '';
%                 app.SetButton_2.Enable = 'off';
%                 app.SetButton_3.Enable = 'off';
%             end
        end

        % Button pushed function: SetButton_3
        function SetButton_3Pushed(app, event)
           app.TestdatasettingsUIFigure.Visible = 'off';
           [FileName,PathName,~] = uigetfile({'*.csv;*.txt'},'Choose raw data file');
           app.TestdatasettingsUIFigure.Visible = 'on';
           temp = strcat(PathName, FileName);
           
           if (any(isspace(temp)))
               app.RawdatafilewithimputedmissingvaluesEditFieldLabel.FontColor = 'red';
               uialert(app.FileDetailsUIFigure,'Spaces in file path not allowed.','Error in file path');
           else
                app.RawdatafilewithimputedmissingvaluesEditFieldLabel.FontColor = 'black';
                app.RawdatafilewithimputedmissingvaluesEditField.Value = strcat(PathName, FileName);
           end
        end

        % Button pushed function: SetButton_2
        function SetButton_2Pushed(app, event)
           app.TestdatasettingsUIFigure.Visible = 'off';
           [FileName,PathName,~] = uigetfile({'*.csv;*.txt'},'Choose test data file');
           app.TestdatasettingsUIFigure.Visible = 'on';
           temp = strcat(PathName, FileName);
           
           if (any(isspace(temp)))
               app.TestdatafilewithimputedmissingvaluesEditFieldLabel.FontColor = 'red';
               uialert(app.TestdatasettingsUIFigure,'Spaces in file path not allowed.','Error in file path');
           else
                app.TestdatafilewithimputedmissingvaluesEditFieldLabel.FontColor = 'black';
                app.TestdatafilewithimputedmissingvaluesEditField.Value = strcat(PathName, FileName);
           end
        end

        % Value changed function: IndexofagecovariateEditField
        function IndexofagecovariateEditFieldValueChanged(app, event)
            value = app.IndexofagecovariateEditField.Value;
            app.IndexofagecovariateEditField.FontColor = 'black';
        end

        % Callback function
        function CovariateindexforxaxisEditFieldValueChanged(app, event)
            value = app.CovariateindexforxaxisEditField.Value;
            app.CovariateindexforxaxisEditField.FontColor = 'black';
        end

        % Callback function
        function CovariateindexforcolouringEditFieldValueChanged(app, event)
            value = app.CovariateindexforcolouringEditField.Value;
            app.CovariateindexforcolouringEditField.FontColor = 'black';
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            back_callback(app.sourceApp);
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TestdatasettingsUIFigure = uifigure;
            app.TestdatasettingsUIFigure.Name = 'Test data settings';
            app.TestfiledetailsPanel = uipanel(app.TestdatasettingsUIFigure);
            app.TestfiledetailsPanel.Title = 'Test file details';
            app.IndexofagecovariateEditFieldLabel = uilabel(app.TestfiledetailsPanel);
            app.IndexofagecovariateEditFieldLabel.Text = 'Index of age covariate:';
            app.IndexofagecovariateEditField = uieditfield(app.TestfiledetailsPanel, 'text');
            app.IndexofagecovariateEditField.ValueChangedFcn = createCallbackFcn(app, @IndexofagecovariateEditFieldValueChanged, true);
            app.RawdatafilewithimputedmissingvaluesEditFieldLabel = uilabel(app.TestfiledetailsPanel);
            app.RawdatafilewithimputedmissingvaluesEditFieldLabel.Text = {'Raw data file with'; 'imputed missing values:'};
            app.RawdatafilewithimputedmissingvaluesEditField = uieditfield(app.TestfiledetailsPanel, 'text');
            app.TestdatafilewithimputedmissingvaluesEditFieldLabel = uilabel(app.TestfiledetailsPanel);
            app.TestdatafilewithimputedmissingvaluesEditFieldLabel.Text = {'Test data file with'; 'imputed missing values:'};
            app.TestdatafilewithimputedmissingvaluesEditField = uieditfield(app.TestfiledetailsPanel, 'text');
            app.SetButton_2 = uibutton(app.TestfiledetailsPanel, 'push');
            app.SetButton_2.ButtonPushedFcn = createCallbackFcn(app, @SetButton_2Pushed, true);
            app.SetButton_2.Text = 'Set';
            app.SetButton_3 = uibutton(app.TestfiledetailsPanel, 'push');
            app.SetButton_3.ButtonPushedFcn = createCallbackFcn(app, @SetButton_3Pushed, true);
            app.SetButton_3.Text = 'Set';
            app.DoneButton = uibutton(app.TestdatasettingsUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.BackButton = uibutton(app.TestdatasettingsUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
        end
    end

    methods (Access = public)

        % Construct app
        function app = misc_window(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TestdatasettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TestdatasettingsUIFigure)
        end
    end
end