classdef plot_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlotsettingsUIFigure          matlab.ui.Figure
        FiguregenerationoptionsPanel  matlab.ui.container.Panel
        GeneratefiguresCheckBox       matlab.ui.control.CheckBox
        CovariatesLabel               matlab.ui.control.Label
        CovariatesTextArea            matlab.ui.control.TextArea
        CovariateindexforxaxisEditField_2Label  matlab.ui.control.Label
        CovariateindexforxaxisEditField  matlab.ui.control.NumericEditField
        CovariateindexforcolouringEditField_2Label  matlab.ui.control.Label
        CovariateindexforcolouringEditField  matlab.ui.control.NumericEditField
        DoneButton                    matlab.ui.control.Button
        BackButton                    matlab.ui.control.Button
    end


    properties (Access = public)
        
        % Specify public properties
        idxXAxis = 0;  
        idxColour = 0;
        genFig = 0;
        sourceApp = '';
        kernelSelectedArr = {};
        varNames = {};
        nConVar = 0;
        nBinVar = 0;
        contArr = {};
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sourceApp, idxXAxis, idxColour, genFig, kernelSelectedArr, varNames, nConVar, nBinVar, contArr)
            app.sourceApp = sourceApp;
            app.idxXAxis = idxXAxis;
            app.idxColour = idxColour;
            app.genFig = genFig;
            app.kernelSelectedArr = kernelSelectedArr;
            app.varNames = varNames;
            app.nConVar = nConVar;
            app.nBinVar = nBinVar;
            app.contArr = contArr;
            
            if (app.genFig == 1)
                app.GeneratefiguresCheckBox.Value = 1;
                app.CovariateindexforxaxisEditField.Enable = 'on';
                app.CovariateindexforcolouringEditField.Enable = 'on';
          %      app.CovariateindexforxaxisEditFieldLabel.Enable = 'on';
              %  app.CovariateindexforcolouringEditFieldLabel.Enable = 'on';
                app.CovariateindexforcolouringEditField.Value = app.idxColour;
                app.CovariateindexforxaxisEditField.Value = app.idxXAxis;
                summary_string = '#. Name  -- Kernel';
                app.CovariatesTextArea.Enable = 'on';
                for i = 1:length(app.varNames)
                    cur_ker = app.kernelSelectedArr(i);
                    cur_type = '';
                    if app.contArr(i) == 0
                        cur_type = 'Continuous';
                    else
                        cur_type = 'Discrete';
                    end
                    str_sum = sprintf('\n%d. %s -- %s (%s)', i, app.varNames{i}, cur_ker.name, cur_type);
                    summary_string = strcat(summary_string, str_sum);
                end
                app.CovariatesTextArea.Value = summary_string;
                
            else
                app.GeneratefiguresCheckBox.Value = 0;
            end

        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.idxColour = app.CovariateindexforcolouringEditField.Value;
            app.idxXAxis = app.CovariateindexforxaxisEditField.Value;
            app.genFig = app.GeneratefiguresCheckBox.Value;
            
            flag = 0;
            
            if (app.GeneratefiguresCheckBox.Value == 1)
               %[~, status] = str2num(app.idxXAxis);
               if(app.idxXAxis ==0 )
                   app.CovariateindexforxaxisEditField.FontColor = 'red';
                   flag = 1;
               end  
               
              % [~, status] = str2num(app.idxColour);
               if(app.idxColour == 0)
                   app.CovariateindexforcolouringEditField.FontColor = 'red';
                   flag = 1;
               end    
            end
            
           if (flag == 1)
                uialert(app.PlotsettingsUIFigure,'Please fill and verify all mandatory fields.','Incomplete specification');
                return;
           end
           
           if (app.idxXAxis > app.nConVar)
               app.CovariateindexforxaxisEditField.FontColor = 'red';
               text = sprintf('Covariate for x axis must be continuous.');
               uialert(app.PlotsettingsUIFigure,text,'Error');
               return;
           end
           
           if (app.idxColour == (app.nConVar + app.nBinVar))
               app.CovariateindexforcolouringEditField.FontColor = 'red';
               uialert(app.PlotsettingsUIFigure,'Covariate for colouring must not be "id".','Error');
               return;
           end
           
           if (app.idxColour <= app.nConVar)
               app.CovariateindexforcolouringEditField.FontColor = 'red';
               uialert(app.PlotsettingsUIFigure,'Covariate for colouring must be discrete.','Error');
               return;
           end
           
           image_window_callback(app.sourceApp, app.idxXAxis, app.idxColour, app.genFig);       
           delete(app);       
        end

        % Value changed function: GeneratefiguresCheckBox
        function GeneratefiguresCheckBoxValueChanged(app, event)
            value = app.GeneratefiguresCheckBox.Value;
            if (value == 1)
                app.CovariateindexforxaxisEditField.Enable = 'on';
                app.CovariateindexforcolouringEditField.Enable = 'on';
              %  app.CovariateindexforxaxisEditFieldLabel.Enable = 'on';
              %  app.CovariateindexforcolouringEditFieldLabel.Enable = 'on';
                summary_string = '#. Name  -- Kernel';
                app.CovariatesTextArea.Enable = 'on';
                for i = 1:length(app.varNames)
                    cur_ker = app.kernelSelectedArr(i);
                    cur_type = '';
                    if app.contArr(i) == 0
                        cur_type = 'Continuous';
                    else
                        cur_type = 'Discrete';
                    end
                    str_sum = sprintf('\n%d. %s -- %s (%s)', i, app.varNames{i}, cur_ker.name, cur_type);
                    summary_string = strcat(summary_string, str_sum);
                end
                app.CovariatesTextArea.Value = summary_string;
            else
                app.CovariatesTextArea.Enable = 'off';
                app.CovariatesTextArea.Value = '';
                app.CovariateindexforxaxisEditField.Enable = 'off';
                app.CovariateindexforcolouringEditField.Enable = 'off';
               % app.CovariateindexforxaxisEditFieldLabel.Enable = 'off';
              %  app.CovariateindexforcolouringEditFieldLabel.Enable = 'off';
                app.CovariateindexforcolouringEditField.Value = '';
                app.CovariateindexforxaxisEditField.Value = '';
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            back_callback(app.sourceApp);
            delete(app);
        end

        % Value changed function: CovariateindexforxaxisEditField
        function CovariateindexforxaxisEditFieldValueChanged(app, event)
            value = app.CovariateindexforxaxisEditField.Value;
            app.CovariateindexforxaxisEditField.FontColor = 'black';
        end

        % Value changed function: CovariateindexforcolouringEditField
        function CovariateindexforcolouringEditFieldValueChanged(app, event)
            value = app.CovariateindexforcolouringEditField.Value;
            app.CovariateindexforcolouringEditField.FontColor = 'black';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlotsettingsUIFigure = uifigure;
            app.PlotsettingsUIFigure.Name = 'Plot settings';
            app.FiguregenerationoptionsPanel = uipanel(app.PlotsettingsUIFigure);
            app.FiguregenerationoptionsPanel.Title = 'Figure generation options';
            app.GeneratefiguresCheckBox = uicheckbox(app.FiguregenerationoptionsPanel);
            app.GeneratefiguresCheckBox.ValueChangedFcn = createCallbackFcn(app, @GeneratefiguresCheckBoxValueChanged, true);
            app.GeneratefiguresCheckBox.Text = '  Generate figures';
            app.CovariatesLabel = uilabel(app.FiguregenerationoptionsPanel);
            app.CovariatesLabel.Enable = 'off';
            app.CovariatesLabel.Text = 'Covariates:';
            app.CovariatesTextArea = uitextarea(app.FiguregenerationoptionsPanel);
            app.CovariatesTextArea.Editable = 'off';
            app.CovariatesTextArea.Enable = 'off';
            app.CovariateindexforxaxisEditField_2Label = uilabel(app.FiguregenerationoptionsPanel);
            app.CovariateindexforxaxisEditField_2Label.Enable = 'off';
            app.CovariateindexforxaxisEditField_2Label.Text = {'Covariate index'; 'for x axis:'};
            app.CovariateindexforxaxisEditField = uieditfield(app.FiguregenerationoptionsPanel, 'numeric');
            app.CovariateindexforxaxisEditField.ValueDisplayFormat = '%.0f';
            app.CovariateindexforxaxisEditField.ValueChangedFcn = createCallbackFcn(app, @CovariateindexforxaxisEditFieldValueChanged, true);
            app.CovariateindexforxaxisEditField.Enable = 'off';
            app.CovariateindexforcolouringEditField_2Label = uilabel(app.FiguregenerationoptionsPanel);
            app.CovariateindexforcolouringEditField_2Label.Enable = 'off';
            app.CovariateindexforcolouringEditField_2Label.Text = {'Covariate index'; 'for colouring:'};
            app.CovariateindexforcolouringEditField = uieditfield(app.FiguregenerationoptionsPanel, 'numeric');
            app.CovariateindexforcolouringEditField.ValueDisplayFormat = '%.0f';
            app.CovariateindexforcolouringEditField.ValueChangedFcn = createCallbackFcn(app, @CovariateindexforcolouringEditFieldValueChanged, true);
            app.CovariateindexforcolouringEditField.Enable = 'off';
            app.DoneButton = uibutton(app.PlotsettingsUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.BackButton = uibutton(app.PlotsettingsUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
        end
    end

    methods (Access = public)

        % Construct app
        function app = plot_window(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlotsettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlotsettingsUIFigure)
        end
    end
end