classdef settings_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GeneralsettingsUIFigure        matlab.ui.Figure
        SettingsPanel                  matlab.ui.container.Panel
        OmittedinteractiontermsEditFieldLabel  matlab.ui.control.Label
        OmittedinteractiontermsEditField  matlab.ui.control.EditField
        commaseparatedlistoptionalLabel  matlab.ui.control.Label
        LOOCVFthresholdEditFieldLabel  matlab.ui.control.Label
        LOOCVFthresholdEditField       matlab.ui.control.EditField
        SCVFthresholdEditFieldLabel    matlab.ui.control.Label
        SCVFthresholdEditField         matlab.ui.control.EditField
        NumberofBootstrapsamplesEditFieldLabel  matlab.ui.control.Label
        NumberofBootstrapsamplesEditField  matlab.ui.control.EditField
        MinvarianceforinteractioncomponentsEditFieldLabel  matlab.ui.control.Label
        MinvarianceforinteractioncomponentsEditField  matlab.ui.control.EditField
        MaxruntimeminsEditFieldLabel   matlab.ui.control.Label
        MaxruntimeminsEditField        matlab.ui.control.EditField
        MaxidletimeminsEditFieldLabel  matlab.ui.control.Label
        MaxidletimeminsEditField       matlab.ui.control.EditField
        DoneButton                     matlab.ui.control.Button
        BackButton                     matlab.ui.control.Button
    end


    properties (Access = public)
        
        % Specify public properties
        sourceApp = '';
        omittedInteractions='';
        loocvThresh='';
        scvThresh='';
        bootstrap='';
        minVar='';
        maxRunTime='';
        maxIdleTime='';
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sourceApp, omittedInteractions, loocvThresh, scvThresh, bootstrap, minVar, maxRunTime, maxIdleTime)
            app.sourceApp = sourceApp;
            app.omittedInteractions = omittedInteractions;
            app.loocvThresh = loocvThresh;
            app.scvThresh = scvThresh;
            app.bootstrap = bootstrap;
            app.minVar = minVar;
            app.maxRunTime = maxRunTime;
            app.maxIdleTime = maxIdleTime;
            app.OmittedinteractiontermsEditField.Value = app.omittedInteractions;
            app.LOOCVFthresholdEditField.Value = num2str(app.loocvThresh);
            app.SCVFthresholdEditField.Value = num2str(app.scvThresh);
            app.NumberofBootstrapsamplesEditField.Value = num2str(app.bootstrap);
            app.MinvarianceforinteractioncomponentsEditField.Value = num2str(app.minVar);
            app.MaxruntimeminsEditField.Value = num2str(app.maxRunTime);
            app.MaxidletimeminsEditField.Value = num2str(app.maxIdleTime);
            
            sourceApp.LonGPUIFigure.Visible = 'off';
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.omittedInteractions = app.OmittedinteractiontermsEditField.Value;
            app.loocvThresh = app.LOOCVFthresholdEditField.Value;
            app.scvThresh = app.SCVFthresholdEditField.Value;
            app.bootstrap = app.NumberofBootstrapsamplesEditField.Value;
            app.minVar = app.MinvarianceforinteractioncomponentsEditField.Value;
            app.maxRunTime = app.MaxruntimeminsEditField.Value;
            app.maxIdleTime = app.MaxidletimeminsEditField.Value;
            flag = 0;
            
            [~, status] = str2num(app.loocvThresh);
           if(strcmp(app.loocvThresh, '') || status == 0 )
               app.LOOCVFthresholdEditFieldLabel.FontColor = 'red';
               flag = 1;
           end   
           
           [~, status] = str2num(app.scvThresh);
           if(strcmp(app.scvThresh, '') || status == 0 )
               app.SCVFthresholdEditFieldLabel.FontColor = 'red';
               flag = 1;
           end   
           
           [~, status] = str2num(app.bootstrap);
           if(strcmp(app.bootstrap, '') || status == 0 )
               app.NumberofBootstrapsamplesEditFieldLabel.FontColor = 'red';
               flag = 1;
           end   
           
           [~, status] = str2num(app.minVar);
           if(strcmp(app.minVar, '') || status == 0 )
               app.MinvarianceforinteractioncomponentsLabel.FontColor = 'red';
               flag = 1;
           end   
           
           if(strcmp(app.maxRunTime, ''))
               app.MaxruntimeminsEditFieldLabel.FontColor = 'red';
               flag = 1;
           end
           
           if(strcmp(app.maxIdleTime, ''))
               app.MaxidletimeminsEditFieldLabel.FontColor = 'red';
               flag = 1;
           end
            
           if (flag == 1)
                uialert(app.GeneralsettingsUIFigure,'Please fill and verify all mandatory fields.','Incomplete specification');
                return;
           end
           
           settings_window_callback(app.sourceApp, app.omittedInteractions, app.loocvThresh, ...
               app.scvThresh, app.bootstrap, app.minVar, app.maxRunTime, app.maxIdleTime);
           
            delete(app);   
        end

        % Value changed function: LOOCVFthresholdEditField
        function LOOCVFthresholdEditFieldValueChanged(app, event)
            value = app.LOOCVFthresholdEditField.Value;
            app.LOOCVFthresholdEditField.FontColor = 'black';
        end

        % Value changed function: SCVFthresholdEditField
        function SCVFthresholdEditFieldValueChanged(app, event)
            value = app.SCVFthresholdEditField.Value;
            app.SCVFthresholdEditField.FontColor = 'black';
        end

        % Value changed function: MaxruntimeminsEditField
        function MaxruntimeminsEditFieldValueChanged(app, event)
            value = app.MaxruntimeminsEditField.Value;
            app.MaxruntimeminsEditField.FontColor = 'black';
            
        end

        % Value changed function: MaxidletimeminsEditField
        function MaxidletimeminsEditFieldValueChanged(app, event)
            value = app.MaxidletimeminsEditField.Value;
            app.MaxidletimeminsEditField.FontColor = 'black';
        end

        % Value changed function: NumberofBootstrapsamplesEditField
        function NumberofBootstrapsamplesEditFieldValueChanged(app, event)
            value = app.NumberofBootstrapsamplesEditField.Value;
            app.NumberofBootstrapsamplesEditField.FontColor = 'black';
        end

        % Value changed function: 
        % MinvarianceforinteractioncomponentsEditField
        function MinvarianceforinteractioncomponentsEditFieldValueChanged(app, event)
            value = app.MinvarianceforinteractioncomponentsEditField.Value;
            app.MinvarianceforinteractioncomponentsEditField.FontColor = 'black';
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            back_callback(app.sourceApp);
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GeneralsettingsUIFigure = uifigure;
            app.GeneralsettingsUIFigure.Name = 'General settings';
            app.SettingsPanel = uipanel(app.GeneralsettingsUIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.OmittedinteractiontermsEditFieldLabel = uilabel(app.SettingsPanel);
            app.OmittedinteractiontermsEditFieldLabel.Text = 'Omitted interaction terms: ';
            app.OmittedinteractiontermsEditField = uieditfield(app.SettingsPanel, 'text');
            app.commaseparatedlistoptionalLabel = uilabel(app.SettingsPanel);
            app.commaseparatedlistoptionalLabel.Text = '(comma separated list, optional)';
            app.LOOCVFthresholdEditFieldLabel = uilabel(app.SettingsPanel);
            app.LOOCVFthresholdEditFieldLabel.Text = 'LOOCVF threshold:';
            app.LOOCVFthresholdEditField = uieditfield(app.SettingsPanel, 'text');
            app.LOOCVFthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @LOOCVFthresholdEditFieldValueChanged, true);
            app.LOOCVFthresholdEditField.Value = '0.8';
            app.SCVFthresholdEditFieldLabel = uilabel(app.SettingsPanel);
            app.SCVFthresholdEditFieldLabel.Text = 'SCVF threshold:';
            app.SCVFthresholdEditField = uieditfield(app.SettingsPanel, 'text');
            app.SCVFthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @SCVFthresholdEditFieldValueChanged, true);
            app.SCVFthresholdEditField.Value = '0.95';
            app.NumberofBootstrapsamplesEditFieldLabel = uilabel(app.SettingsPanel);
            app.NumberofBootstrapsamplesEditFieldLabel.Text = 'Number of Bootstrap samples:';
            app.NumberofBootstrapsamplesEditField = uieditfield(app.SettingsPanel, 'text');
            app.NumberofBootstrapsamplesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofBootstrapsamplesEditFieldValueChanged, true);
            app.NumberofBootstrapsamplesEditField.Value = '20000';
            app.MinvarianceforinteractioncomponentsEditFieldLabel = uilabel(app.SettingsPanel);
            app.MinvarianceforinteractioncomponentsEditFieldLabel.Text = {'Min variance for interaction '; 'components:'};
            app.MinvarianceforinteractioncomponentsEditField = uieditfield(app.SettingsPanel, 'text');
            app.MinvarianceforinteractioncomponentsEditField.ValueChangedFcn = createCallbackFcn(app, @MinvarianceforinteractioncomponentsEditFieldValueChanged, true);
            app.MinvarianceforinteractioncomponentsEditField.Value = '0.01';
            app.MaxruntimeminsEditFieldLabel = uilabel(app.SettingsPanel);
            app.MaxruntimeminsEditFieldLabel.Text = 'Max run time (mins):';
            app.MaxruntimeminsEditField = uieditfield(app.SettingsPanel, 'text');
            app.MaxruntimeminsEditField.ValueChangedFcn = createCallbackFcn(app, @MaxruntimeminsEditFieldValueChanged, true);
            app.MaxruntimeminsEditField.Value = 'Inf';
            app.MaxidletimeminsEditFieldLabel = uilabel(app.SettingsPanel);
            app.MaxidletimeminsEditFieldLabel.Text = 'Max idle time (mins):';
            app.MaxidletimeminsEditField = uieditfield(app.SettingsPanel, 'text');
            app.MaxidletimeminsEditField.ValueChangedFcn = createCallbackFcn(app, @MaxidletimeminsEditFieldValueChanged, true);
            app.MaxidletimeminsEditField.Value = '120';
            app.DoneButton = uibutton(app.GeneralsettingsUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.BackButton = uibutton(app.GeneralsettingsUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
        end
    end

    methods (Access = public)

        % Construct app
        function app = settings_window(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GeneralsettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GeneralsettingsUIFigure)
        end
    end
end